classdef TicTacToe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlayerselectButtonGroup     matlab.ui.container.ButtonGroup
        XButton                     matlab.ui.control.RadioButton
        OButton                     matlab.ui.control.RadioButton
        ExitButton                  matlab.ui.control.Button
        TICTACTOELabel              matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        CurrentPlayerTextAreaLabel  matlab.ui.control.Label
        CurrentPlayerTextArea       matlab.ui.control.TextArea
        UIAxes                      matlab.ui.control.UIAxes
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        winnerLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        camObj;
        frameObj;
        imObj;
        
        grayFrame;
        
        gridState = [' ', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '];
        
        playerHuman; playerTurn;
        gameOver = false;
        updated = false;
        %turn_flag = true;
    end
    
    methods (Access = private)
        
        function [BW,maskedImage] = segmentImage(~,X, str, threshold, process)
            
            if(threshold == 0)
                BW = imbinarize(X);
            else
                BW = X > threshold;
            end
            % Invert mask
            if strcmp(str, 'black')
                BW = imcomplement(BW);
            end
            
            if process
                
                radius = 8;
                decomposition = 0;
                se = strel('disk', radius, decomposition);
                BW = imdilate(BW, se);
                
                
                % Open mask with disk
                radius = 3;
                decomposition = 0;
                se = strel('disk', radius, decomposition);
                BW = imopen(BW, se);
                
                
                % Erode mask with disk
                radius = 4;
                decomposition = 0;
                se = strel('disk', radius, decomposition);
                BW = imerode(BW, se);
                
                %     Close mask with disk
                radius = 3;
                decomposition = 0;
                se = strel('disk', radius, decomposition);
                BW = imclose(BW, se);
                
            end
            
            % Create masked image.
            maskedImage = X;
            maskedImage(~BW) = 0;
            
        end
        
        function [lines,bb] = showHoughLines(~,BW, str, angleLow, angleHigh,threshold,gap,show)
            
            [H,theta,rho] = hough(BW, str, angleLow:0.5:angleHigh);
            
            P = houghpeaks(H,5,'threshold',ceil(0.3*max(H(:))));
            lines = houghlines(BW,theta,rho,P,'FillGap',gap,'MinLength',threshold);
            %len = zeros(1,length(lines));
            
            % -- hold on
            
            smallX = 1280; smallY = 720; bigX = 0; bigY = 0;
            
            for k = 1:length(lines)
                xy = [lines(k).point1; lines(k).point2];
                if(show)
                    %       plot(xy(:,1),xy(:,2),'LineWidth',2,'Color','green');
                    % Plot beginnings and ends of lines
                    %       plot(xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
                    %       plot(xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');
                end
                if xy(1,1) < xy(2,1)
                    if smallX > xy(1,1)
                        smallX = xy(1,1);
                    end
                    if bigX < xy(2,1)
                        bigX = xy(2,1);
                    end
                else
                    if smallX > xy(2,1)
                        smallX = xy(2,1);
                    end
                    if bigX < xy(1,1)
                        bigX = xy(1,1);
                    end
                end
                
                if xy(1,2) < xy(2,2)
                    if smallY > xy(1,2)
                        smallY = xy(1,2);
                    end
                    if bigY < xy(2,2)
                        bigY = xy(2,2);
                    end
                else
                    if smallY > xy(2,2)
                        smallY = xy(2,2);
                    end
                    if bigY < xy(1,2)
                        bigY = xy(1,2);
                    end
                end
                
            end
            
            bb= [smallX smallY bigX bigY];
            
        end
        
        function [croppedBoard, boardBoundingBox] = detectBoard(app,Ig)
            
            K = wiener2(Ig,[13 13]);
            
            BN = edge(K,'prewitt');
            
            se = strel('disk',3);
            BN = imdilate(BN, se);
            se = strel('line',13,20);
            BN = imdilate(BN, se);
            
            %subplot(2,1,1), imshow(BN);
            
            threshold = 100;
            
            [BW,~] = segmentImage(app,Ig, 'white', 110, false);
            
            show = false;
            
            cc = bwconncomp(BW, 4);
            tictac_data = regionprops(cc, 'all');
            
            t_area = [tictac_data.Area];
            
            [~,maxInd] = max(t_area);
            bb = tictac_data(maxInd).BoundingBox;
            
            BN = imcrop(BN, bb);
            
            %hold on
            
            [~,bb0] = showHoughLines(app,BN,'Theta',-90,-80,threshold,5, show);
            [~,bb90] = showHoughLines(app,BN,'Theta',-10,10,threshold,5, show);
            [~,bb_90] = showHoughLines(app,BN,'Theta',80,89,threshold,5, show);
            
            bbL = [bb0(1) bb0(3) bb90(1) bb90(3) bb_90(1) bb_90(3)];
            bbU = [bb0(2) bb0(4) bb90(2) bb90(4) bb_90(2) bb_90(4)];
            
            %
            lowX = min(bbL, [], 'all');
            upX =  max(bbL, [], 'all');
            lowY = min(bbU, [], 'all');
            upY = max(bbU, [], 'all');
            
            boardBoundingBox = [lowX lowY upX-lowX upY-lowY];
            
            croppedBoard = imcrop(Ig,boardBoundingBox);
            
        end
        
        function [croppedGrid, gridBoundingBox] = detectGrid(app,croppedBoard)
            
            [BW,~] = segmentImage(app,croppedBoard, 'black',0, false);
            
            se = strel('disk',3);
            BW = imdilate(BW, se);
            se = strel('line',13,20);
            BW = imdilate(BW, se);
            
            threshold = 600; gap = 500;
            
            show = false;
            % subplot(2,1,1);
            % imshow(BW);
            % hold on
            [lines0,~] = showHoughLines(app,BW,'Theta',-90,-80,threshold,gap, show);
            [lines90,~] = showHoughLines(app,BW,'Theta',-10,10,threshold,gap, show);
            [lines_90,~] = showHoughLines(app,BW,'Theta',80,89,threshold,gap, show);
            %hold off
            
            if(isempty(lines0) && isempty(lines90) && isempty(lines_90))
                croppedGrid = 0;
                gridBoundingBox = 0;
                
            else
                % Connected component analysis
                cc = bwconncomp(BW, 4);
                
                % Region Properties
                tictac_data = regionprops(cc, 'all');
                
                t_en = [tictac_data.EulerNumber];
                t_area = [tictac_data.Area];
                t_major = [tictac_data.MajorAxisLength];
                t_minor = [tictac_data.MinorAxisLength];
                
                % aboveThresholdIndexes = t_area > 70000;
                %  possible_grid = t_area(aboveThresholdIndexes)  %above 10k -> XOs +100k >> grid
                
                flag = 0;
                %  figure, imshow(BW);
                %    imageRegionAnalyzer(BW)
                
                max_area = max(t_area);
                
                for n = 1:cc.NumObjects
                    t_a = t_area(n);
                    t_e = t_en(n);
                    t_min = t_minor(n);
                    t_maj = t_major(n);
                    
                    if t_a > floor(max_area / 5)
                        
                        if t_e < 1
                            
                            if floor(norm(t_maj - t_min)) < floor(max(t_maj, t_min)/ 3)
                                
                                flag = n;
                            end
                        end
                        
                        
                    end
                end
                
                if(flag == 0)
                    croppedGrid = 0;
                    gridBoundingBox = 0;
                else
                    gridBoundingBox = tictac_data(flag).BoundingBox;
                    croppedGrid = imcrop(croppedBoard,gridBoundingBox);
                end
                
                % subplot(2,1,2);
                % rgb = insertObjectAnnotation(croppedBoard, 'rectangle', gridBoundingBox, 'Grid', 'LineWidth',5);
                % imshow(rgb);
            end
            
        end
        
        function state = extractState(app,croppedGrid)
            
            state = [' ', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '];
            
            croppedGrid = imresize(croppedGrid, [800 NaN]); %for consistency
            
            [BW,~] = segmentImage(app,croppedGrid, 'black',0, true);
            
            
            cc = bwconncomp(BW, 4);
            cc.NumObjects;
            tictac_data = regionprops(cc, 'all');
            
            t_en = [tictac_data.EulerNumber];
            t_area = [tictac_data.Area];
            t_bounding = [tictac_data.BoundingBox];
            
           % imageRegionAnalyzer(BW)
            
            %I2 = croppedGrid;
            row_mod = floor(size(croppedGrid,1)/3);
            col_mod = floor(size(croppedGrid,2)/3);
            
            for n = 1:cc.NumObjects
                
                t_a = t_area(n);
                t_e = t_en(n);
                t_bb = t_bounding(:,(n-1)*4+1:n*4);
                
                if t_a < 20000 && t_a > 2000
                    if t_e == 1
                        Shape = 'X';
                    else
                        Shape = 'O';
                    end
                    
                    t_locX = floor(t_bb(1)/col_mod);
                    t_locY = floor(t_bb(2)/row_mod);
                    
                    k = (t_locX+1) + (t_locY*3);
                    
                    state(k) = Shape;
                    %rgb = insertObjectAnnotation(I2, 'rectangle', tictac_data(n).BoundingBox, Shape, 'Color', 'yellow');
                    %imshow(rgb)
                    %I2 = rgb;
                end
                
                if t_a > 300000
                    state(10) = ' ';
                end
            end
            
            if length(state) > 9
                state = [' ', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '];
            end
        end
        
        function [state,gameOver,updated] = updateState(app,currentState, newState, playerTurn) %sonradan önceki stateparam olarak gelebilir karşl. için
            
            equality_count = 0;
            unequality_count = 0;
            unequality_flag = 0;
            
            updated = false;
            
            if xor(playerTurn, app.playerHuman) %it means computer's turn
                state = newState;
                updated = true;
            else %human's turn = playerTurn
                state = currentState;
                
                for i = 1:9
                    
                    if strcmp(newState(i),currentState(i))
                        equality_count = equality_count + 1;
                    else
                        if strcmp(currentState(i),' ')
                            
                            if playerTurn
                                if strcmp(newState(i),'X') %player = X computer = O
                                    unequality_count = unequality_count + 1;
                                    unequality_flag = i;
                                end
                            else % player = O computer = X
                                if strcmp(newState(i),'O')
                                    unequality_count = unequality_count + 1;
                                    unequality_flag = i;
                                end
                            end
                        else
                            if playerTurn %computer is O
                                if strcmp(currentState(i),'O') && strcmp(newState(i),' ')
                                    equality_count = equality_count + 1;
                                end
                            else % computer is X
                                if strcmp(currentState(i),'X') && strcmp(newState(i),' ')
                                    equality_count = equality_count + 1;
                                end
                            end
                        end
                    end
                end
                
                if( equality_count == 8 && unequality_count == 1) %this is not accurate, if an X is detected as O mistakenly, then it fails
                    
                    if playerTurn
                        state(unequality_flag) = 'X';
                    else
                        state(unequality_flag) = 'O';
                    end
                    updated = true;
                end
            end
            
            gameOver = isOver(app,state);
        end
        
        function updateIndex = makeDecision(app, gridState)
            
            %             flag = 1;
            %             i = 1;
            %             updateIndex = 0;
            %
            %             while flag
            %                 if strcmp(gridState(i),' ')
            %                     flag = 0;
            %                     updateIndex = i;
            %                 end
            %                 i = i + 1;
            %             end
            %
            %             gridState = app.gridState;
            %
            %             if app.playerTurn
            %                 gridState(updateIndex) = 'X';
            %             else
            %                 gridState(updateIndex) = 'O';
            %             end
            
            board = [0 0 0 0 0 0 0 0 0];
            k = 1;
            
            for i = 1:9
                if strcmp(gridState(i),' ')
                    board(i) = 0;
                    availableSquare(k) = i;
                    k = k+1;
                    
                elseif  strcmp(gridState(i),'X')
                    if app.playerHuman
                        board(i) = 2;
                    else
                        board(i) = 1;
                    end
                else
                    if app.playerHuman
                        board(i) = 1;
                    else
                        board(i) = 2;
                    end
                end
            end
            
            num=0;
            i=1;
            j=2;
            pause(0.5);
            
            %try to win, if u can't try to block
            while num==0
                if i==1
                   	s=[1 2 3];
                elseif i==2
                   	s=[4 5 6];
                elseif i==3
                   	s=[7 8 9];
                elseif i==4
                   	s=[1 4 7];
                elseif i==5
                   	s=[2 5 8];
                elseif i==6
                   	s=[3 6 9];
                elseif i==7
                   	s=[1 5 9];
                elseif i==8
                   	s=[3 5 7];
                elseif i==9 && j==2
                    j=1;
                    i=1;
                elseif i==9 && j==1
                    num= availableSquare(ceil(rand*(length(availableSquare)))); %pick any sq if everything fails
                end
               	
               	if board(s(1))==j && board(s(2))==j && board(s(3))==0
                    num=s(3);
               	elseif board(s(1))==j && board(s(2))==0 && board(s(3))==j
                    num=s(2);
               	elseif board(s(1))==0 && board(s(2))==j && board(s(3))==j
                    num=s(1);
               	end
                i=i+1;
            end
            
            updateIndex = num;
            
            gridState = app.gridState;
            
            if app.playerTurn
                gridState(updateIndex) = 'X';
            else
                gridState(updateIndex) = 'O';
            end
            
            [app.gridState,app.gameOver,app.updated] = updateState(app, app.gridState, gridState, app.playerTurn);
        end
        
        function over = isOver(app,state)
            
            over = false;
            flag = 0;
            win = ' ';
            
            if or(strcmp(state(1),'X') && strcmp(state(2),'X')' && strcmp(state(3),'X'), strcmp(state(1),'O') && strcmp(state(2),'O')' && strcmp(state(3),'O'))
                win = state(1);
            elseif or(strcmp(state(4),'X') && strcmp(state(5),'X')' && strcmp(state(6),'X'), strcmp(state(4),'O') && strcmp(state(5),'O')' && strcmp(state(6),'O'))
                win = state(4);
            elseif or(strcmp(state(7),'X') && strcmp(state(8),'X')' && strcmp(state(9),'X'), strcmp(state(7),'O') && strcmp(state(8),'O')' && strcmp(state(9),'O'))
                win = state(7);
            elseif or(strcmp(state(1),'X') && strcmp(state(4),'X')' && strcmp(state(7),'X'), strcmp(state(1),'O') && strcmp(state(4),'O')' && strcmp(state(7),'O'))
                win= state(1);
            elseif or(strcmp(state(2),'X') && strcmp(state(5),'X')' && strcmp(state(8),'X'), strcmp(state(2),'O') && strcmp(state(5),'O')' && strcmp(state(8),'O'))
                win= state(2);
            elseif or(strcmp(state(3),'X') && strcmp(state(6),'X')' && strcmp(state(9),'X'), strcmp(state(3),'O') && strcmp(state(6),'O')' && strcmp(state(9),'O'))
                win= state(3);
            elseif or(strcmp(state(1),'X') && strcmp(state(5),'X')' && strcmp(state(9),'X'), strcmp(state(1),'O') && strcmp(state(5),'O')' && strcmp(state(9),'O'))
                win= state(1);
            elseif or(strcmp(state(3),'X') && strcmp(state(5),'X')' && strcmp(state(7),'X'), strcmp(state(3),'O') && strcmp(state(5),'O')' && strcmp(state(7),'O'))
                win= state(3);
            end
            
            
            for i = 1: length(state)
                if(state(i) == ' ')
                    flag = flag + 1;
                end
            end
            
            if (flag == 0)
                over = true;
                app.CurrentPlayerTextArea.Visible = 'off';
                app.CurrentPlayerTextAreaLabel.Visible = 'off';
            end
            
            if win ~= ' '
                
                over = true;
                app.CurrentPlayerTextArea.Visible = 'off';
                app.CurrentPlayerTextAreaLabel.Visible = 'off';
                
                % someone won
                app.winnerLabel.Visible = 'on';
                
                if strcmp(win,'X')
                    if app.playerHuman
                        app.winnerLabel.Text = 'PLAYER WON';
                    else
                        app.winnerLabel.Text = 'COMPUTER WON';
                    end
                else
                    if app.playerHuman
                        app.winnerLabel.Text = 'COMPUTER WON';
                    else
                        app.winnerLabel.Text = 'PLAYER WON';
                    end
                end
                
            elseif over
                %tie
                app.winnerLabel.Visible = 'on';
                app.winnerLabel.Text = 'TIE';
            end
            
            
        end
        
        function printState(app,state)
            
            app.Label.Text = state(1); app.Label_2.Text = state(2); app.Label_3.Text = state(3);
            app.Label_4.Text = state(4); app.Label_5.Text = state(5); app.Label_6.Text = state(6);
            app.Label_7.Text = state(7); app.Label_8.Text = state(8); app.Label_9.Text = state(9);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq
        end

        % Callback function
        function ButtonValueChanged(app, event)
            %value = app.StartButton.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.PlayerselectButtonGroup.Visible = 'off';
            app.StartButton.Visible = 'off';
            app.UIAxes.Visible = 'on';
            app.CurrentPlayerTextAreaLabel.Visible = 'on';
            app.CurrentPlayerTextArea.Visible = 'on';
            
            app.Label.Visible = 'on';app.Label_2.Visible = 'on';app.Label_3.Visible = 'on';
            app.Label_4.Visible = 'on';app.Label_5.Visible = 'on';app.Label_6.Visible = 'on';
            app.Label_7.Visible = 'on';app.Label_8.Visible = 'on';app.Label_9.Visible = 'on';
            
            
            app.playerTurn = logical(randi([0, 1]));
            
            
            if app.XButton.Value
                app.playerHuman = 1; % = 'X';
            else
                app.playerHuman = 0; % = 'O';
            end
            
            app.camObj = webcam(1);
            
            %  preview(app.camObj); %why this has so much energy impact?
            
            while ~app.gameOver
                
                if app.playerTurn
                    app.CurrentPlayerTextArea.Value = 'X';
                else
                    app.CurrentPlayerTextArea.Value = 'O';
                end
                
                if xor(app.playerTurn, app.playerHuman) %it means computer's turn
                    index = makeDecision(app,app.gridState);
                    
                    if app.updated %bu da bir fonksiyonun içine gireiblir (state update'in mesela)
                        printState(app,app.gridState);
                        app.playerTurn = ~app.playerTurn;
                        
                        if app.playerTurn
                            app.CurrentPlayerTextArea.Value = 'X';
                        else
                            app.CurrentPlayerTextArea.Value = 'O';
                        end
                    end
                else
                    pause(2); %--------------------
                    
                    app.frameObj = snapshot(app.camObj);
                    
                    % app.imObj = image(app.UIAxes, zeros(size(app.frameObj),'uint8'));
                    % axis(app.UIAxes, 'image');
                    
                    Ig = rgb2gray(app.frameObj);
                    
                    croppedBoard = Ig;
                    %[croppedBoard, ~] = detectBoard(app,Ig);
                    [croppedGrid, ~] = detectGrid(app,croppedBoard);
                    
                    if croppedGrid == 0
                        % fprintf('Game update: No grid detected yet. \n');
                    else
                        
                        newState = extractState(app,croppedGrid);
                        
                        [app.gridState,app.gameOver,app.updated] = updateState(app,app.gridState, newState, app.playerTurn);
                        
                        if app.updated
                            printState(app,app.gridState);
                            
                            app.playerTurn = ~app.playerTurn;
                            
                            if app.playerTurn
                                app.CurrentPlayerTextArea.Value = 'X';
                            else
                                app.CurrentPlayerTextArea.Value = 'O';
                            end
                        end
                        
                    end
                    
                end
                
                
            end
            
            
        end

        % Value changed function: CurrentPlayerTextArea
        function CurrentPlayerTextAreaValueChanged(app, event)
            %value = app.CurrentPlayerTextArea.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
           % delete(app.camObj)
        end

        % Selection changed function: PlayerselectButtonGroup
        function PlayerselectButtonGroupSelectionChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PlayerselectButtonGroup = uibuttongroup(app.UIFigure);
            app.PlayerselectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlayerselectButtonGroupSelectionChanged, true);
            app.PlayerselectButtonGroup.Title = 'Player select';
            app.XButton = uiradiobutton(app.PlayerselectButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.OButton = uiradiobutton(app.PlayerselectButtonGroup);
            app.OButton.Text = 'O';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TICTACTOELabel = uilabel(app.UIFigure);
            app.TICTACTOELabel.Text = 'TIC TAC TOE';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.CurrentPlayerTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentPlayerTextAreaLabel.Visible = 'off';
            app.CurrentPlayerTextAreaLabel.Text = 'Current Player:';
            app.CurrentPlayerTextArea = uitextarea(app.UIFigure);
            app.CurrentPlayerTextArea.ValueChangedFcn = createCallbackFcn(app, @CurrentPlayerTextAreaValueChanged, true);
            app.CurrentPlayerTextArea.Editable = 'off';
            app.CurrentPlayerTextArea.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Visible = 'off';
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Visible = 'off';
            app.Label_9.Text = '';
            app.winnerLabel = uilabel(app.UIFigure);
            app.winnerLabel.Visible = 'off';
            app.winnerLabel.Text = 'winner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end